classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TrainingTab                     matlab.ui.container.Tab
        TrainingCNNButtonGroup          matlab.ui.container.ButtonGroup
        TrainingAlexNetButton           matlab.ui.control.RadioButton
        TrainingGoogleNetButton         matlab.ui.control.RadioButton
        TrainingTitle                   matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeLabel              matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        SolverContainer                 matlab.ui.container.ButtonGroup
        rmspropButton                   matlab.ui.control.RadioButton
        adamButton                      matlab.ui.control.RadioButton
        sgdmButton                      matlab.ui.control.RadioButton
        StartTrainingButton             matlab.ui.control.Button
        ClassifyTab                     matlab.ui.container.Tab
        ClassifyTabImage                matlab.ui.control.Image
        ClassifyCNNButtonGroup          matlab.ui.container.ButtonGroup
        ClassifyAlexNetButton           matlab.ui.control.RadioButton
        ClassifyGoogleNetButton         matlab.ui.control.RadioButton
        ResizeButton                    matlab.ui.control.Button
        ChooseImageButton               matlab.ui.control.Button
        StartClassifyButton             matlab.ui.control.Button
        ClassifyTitle                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        imageSelected % Image selected for resizing and/or sorting
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            solver = app.SolverContainer.SelectedObject.Text;
            miniBatchSize = app.MiniBatchSizeEditField.Value;
            maxEpochs = app.MaxEpochsEditField.Value;
            initialTraining = app.InitialLearnRateEditField.Value;
            method = app.TrainingCNNButtonGroup.SelectedObject.Text;
            switch method
                case "GoogleNet"
                    trainingImagesGoogleNet(solver, miniBatchSize, maxEpochs, initialTraining);
                case "AlexNet"
                    trainingImagesAlexNet(solver, miniBatchSize, maxEpochs, initialTraining);
            end
        end

        % Button pushed function: StartClassifyButton
        function StartClassifyButtonPushed(app, event)
            classifyImage(app.imageSelected, app.ClassifyCNNButtonGroup.SelectedObject.Text);
        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            filter={'*.jpg';'*.png'};
            [file,path] = uigetfile(filter);
            fileImage=fullfile(path,file);
            app.ClassifyTabImage.ImageSource=fileImage;
            app.imageSelected = fileImage;
        end

        % Callback function
        function RedimensionarSeleccionarImagenButtonPushed(app, event)
            filter={'*.jpg';'*.png'};
            [file,path] = uigetfile(filter);
            fileImage=fullfile(path,file);
            app.urlResize=fileImage;
        end

        % Button pushed function: ResizeButton
        function ResizeButtonButtonPushed(app, event)
            resizeImage(app.imageSelected, app.ClassifyCNNButtonGroup.SelectedObject.Text);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'ENTRENAMIENTO';
            app.StartTrainingButton = uibutton(app.TrainingTab, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Comenzar entrenamiento';
            app.SolverContainer = uibuttongroup(app.TrainingTab);
            app.SolverContainer.Title = 'Solver';
            app.sgdmButton = uiradiobutton(app.SolverContainer);
            app.sgdmButton.Text = 'sgdm';
            app.sgdmButton.Value = true;
            app.adamButton = uiradiobutton(app.SolverContainer);
            app.adamButton.Text = 'adam';
            app.rmspropButton = uiradiobutton(app.SolverContainer);
            app.rmspropButton.Text = 'rmsprop';
            app.MaxEpochsEditFieldLabel = uilabel(app.TrainingTab);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.TrainingTab, 'numeric');
            app.InitialLearnRateEditFieldLabel = uilabel(app.TrainingTab);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.TrainingTab, 'numeric');
            app.MiniBatchSizeLabel = uilabel(app.TrainingTab);
            app.MiniBatchSizeLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.TrainingTab, 'numeric');
            app.TrainingTitle = uilabel(app.TrainingTab);
            app.TrainingTitle.Text = 'ENTRENAMIENTO';
            app.TrainingCNNButtonGroup = uibuttongroup(app.TrainingTab);
            app.TrainingCNNButtonGroup.Title = 'CNN';
            app.TrainingGoogleNetButton = uiradiobutton(app.TrainingCNNButtonGroup);
            app.TrainingGoogleNetButton.Text = 'GoogleNet';
            app.TrainingGoogleNetButton.Value = true;
            app.TrainingAlexNetButton = uiradiobutton(app.TrainingCNNButtonGroup);
            app.TrainingAlexNetButton.Text = 'AlexNet';
            app.ClassifyTab = uitab(app.TabGroup);
            app.ClassifyTab.Title = 'CLASIFICACIÓN';
            app.ClassifyTitle = uilabel(app.ClassifyTab);
            app.ClassifyTitle.Text = 'CLASIFICACIÓN';
            app.StartClassifyButton = uibutton(app.ClassifyTab, 'push');
            app.StartClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @StartClassifyButtonPushed, true);
            app.StartClassifyButton.Text = 'Comenzar clasificación';
            app.ChooseImageButton = uibutton(app.ClassifyTab, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Selecciona imagen';
            app.ResizeButton = uibutton(app.ClassifyTab, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonButtonPushed, true);
            app.ResizeButton.Text = 'Redimensionar';
            app.ClassifyCNNButtonGroup = uibuttongroup(app.ClassifyTab);
            app.ClassifyCNNButtonGroup.Title = 'CNN';
            app.ClassifyGoogleNetButton = uiradiobutton(app.ClassifyCNNButtonGroup);
            app.ClassifyGoogleNetButton.Text = 'GoogleNet';
            app.ClassifyGoogleNetButton.Value = true;
            app.ClassifyAlexNetButton = uiradiobutton(app.ClassifyCNNButtonGroup);
            app.ClassifyAlexNetButton.Text = 'AlexNet';
            app.ClassifyTabImage = uiimage(app.ClassifyTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end